classdef gui_kupovina < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        izlazButton              matlab.ui.control.Button
        ponistiButton            matlab.ui.control.Button
        ocjenaButton             matlab.ui.control.Button
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        ekspertniEditField       matlab.ui.control.NumericEditField
        ekspertniEditFieldLabel  matlab.ui.control.Label
        annEditField             matlab.ui.control.NumericEditField
        annEditFieldLabel        matlab.ui.control.Label
        fuzzyEditField           matlab.ui.control.NumericEditField
        fuzzyEditFieldLabel      matlab.ui.control.Label
        godisteEditField         matlab.ui.control.NumericEditField
        godisteEditFieldLabel    matlab.ui.control.Label
        ubrzanjeEditField        matlab.ui.control.NumericEditField
        ubrzanjeEditFieldLabel   matlab.ui.control.Label
        potrosnjaEditField       matlab.ui.control.NumericEditField
        potrosnjaEditFieldLabel  matlab.ui.control.Label
        stanjeEditField          matlab.ui.control.NumericEditField
        stanjeEditFieldLabel     matlab.ui.control.Label
        cijenaEditField          matlab.ui.control.NumericEditField
        cijenaEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ocjenaButton
        function ocjenaButtonPushed(app, event)
            cijena    = app.cijenaEditField.Value;
            stanje    = app.stanjeEditField.Value;
            potrosnja = app.potrosnjaEditField.Value;
            ubrzanje  = app.ubrzanjeEditField.Value;
            godiste   = app.godisteEditField.Value;

            % 1. FUZZY sistem
            fis = readfis('kupovinaMotora');
            izlaz_fuzzy = evalfis(fis, [cijena stanje potrosnja ubrzanje godiste]);

            % 2. Posalji ulaze u base workspace (za Simulink)
            assignin('base','cijena',cijena);
            assignin('base','stanje',stanje);
            assignin('base','potrosnja',potrosnja);
            assignin('base','ubrzanje',ubrzanje);
            assignin('base','godiste',godiste);

            % 3. Pokreni Simulink EkspertniSistem
            load_system('EkspertniSistem');
            set_param('EkspertniSistem','SimulationCommand','start');
            pause(1);

            izlaz_ann = evalin('base','izlazSim1');  % ANN
            izlaz_es  = evalin('base','izlazSim');   % Ekspertni

            % 4. Prikazi brojeve
           app.fuzzyEditField.Value     = izlaz_fuzzy(1);
            app.annEditField.Value       = izlaz_ann(1);
            app.ekspertniEditField.Value = izlaz_es(1);
        end

        % Button pushed function: ponistiButton
        function ponistiButtonPushed(app, event)
            app.cijenaEditField.Value = 0;
            app.stanjeEditField.Value = 0;
            app.potrosnjaEditField.Value = 0;
            app.ubrzanjeEditField.Value = 0;
            app.godisteEditField.Value = 0;
            app.fuzzyEditField.Value = 0;
            app.annEditField.Value = 0;
            app.ekspertniEditField.Value = 0; 
        end

        % Button pushed function: izlazButton
        function izlazButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cijenaEditFieldLabel = uilabel(app.UIFigure);
            app.cijenaEditFieldLabel.Text = 'cijena';
            app.cijenaEditField = uieditfield(app.UIFigure, 'numeric');
            app.stanjeEditFieldLabel = uilabel(app.UIFigure);
            app.stanjeEditFieldLabel.Text = 'stanje';
            app.stanjeEditField = uieditfield(app.UIFigure, 'numeric');
            app.potrosnjaEditFieldLabel = uilabel(app.UIFigure);
            app.potrosnjaEditFieldLabel.Text = 'potrosnja';
            app.potrosnjaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ubrzanjeEditFieldLabel = uilabel(app.UIFigure);
            app.ubrzanjeEditFieldLabel.Text = 'ubrzanje';
            app.ubrzanjeEditField = uieditfield(app.UIFigure, 'numeric');
            app.godisteEditFieldLabel = uilabel(app.UIFigure);
            app.godisteEditFieldLabel.Text = 'godiste';
            app.godisteEditField = uieditfield(app.UIFigure, 'numeric');
            app.fuzzyEditFieldLabel = uilabel(app.UIFigure);
            app.fuzzyEditFieldLabel.Text = 'fuzzy';
            app.fuzzyEditField = uieditfield(app.UIFigure, 'numeric');
            app.annEditFieldLabel = uilabel(app.UIFigure);
            app.annEditFieldLabel.Text = 'ann';
            app.annEditField = uieditfield(app.UIFigure, 'numeric');
            app.ekspertniEditFieldLabel = uilabel(app.UIFigure);
            app.ekspertniEditFieldLabel.Text = 'ekspertni';
            app.ekspertniEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ocjenaButton = uibutton(app.UIFigure, 'push');
            app.ocjenaButton.ButtonPushedFcn = createCallbackFcn(app, @ocjenaButtonPushed, true);
            app.ocjenaButton.Text = 'ocjena';
            app.ponistiButton = uibutton(app.UIFigure, 'push');
            app.ponistiButton.ButtonPushedFcn = createCallbackFcn(app, @ponistiButtonPushed, true);
            app.ponistiButton.Text = 'ponisti';
            app.izlazButton = uibutton(app.UIFigure, 'push');
            app.izlazButton.ButtonPushedFcn = createCallbackFcn(app, @izlazButtonPushed, true);
            app.izlazButton.Text = 'izlaz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_kupovina

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end